classdef app_indireto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRACIndiretoUIFigure    matlab.ui.Figure
        Value1EditFieldLabel    matlab.ui.control.Label
        Value1EditField         matlab.ui.control.NumericEditField
        Value2EditFieldLabel    matlab.ui.control.Label
        Value2EditField         matlab.ui.control.NumericEditField
        CompareButton           matlab.ui.control.Button
        ParameterDropDownLabel  matlab.ui.control.Label
        ParameterDropDown       matlab.ui.control.DropDown
        SaveCheckBox            matlab.ui.control.CheckBox
        tEditFieldLabel         matlab.ui.control.Label
        tEditField              matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            evalin('base',strcat(app.ParameterDropDown.Value,'1=',num2str(app.Value1EditField.Value),';'))
            evalin('base',strcat(app.ParameterDropDown.Value,'2=',num2str(app.Value2EditField.Value),';'))
            switch app.ParameterDropDown.Value
                case 'ap'
                    evalin('base','changed=1;')
                case 'am'
                    evalin('base','changed=2;')
                case 'af'
                    evalin('base','changed=3;')
                case'gamma'
                    evalin('base','changed=4;')
                case'yp0'
                    evalin('base','changed=5;')
                case'theta0'
                    evalin('base','changed=6;')
            end
            evalin('base','simu_indireto')
            
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            parameter = app.ParameterDropDown.Value;
            switch parameter
                case 'ap'
                    app.Value1EditField.Limits = [-10 -1];
                    app.Value2EditField.Limits = [-10 -1];
                case 'am'
                    app.Value1EditField.Limits = [1 10];
                    app.Value2EditField.Limits = [1 10];
                case 'af'
                    app.Value1EditField.Limits = [1 10];
                    app.Value2EditField.Limits = [1 10];
                case 'gamma'
                    app.Value1EditField.Limits = [1 100];
                    app.Value2EditField.Limits = [1 100];
                case 'yp0'
                    app.Value1EditField.Limits = [-5 5];
                    app.Value2EditField.Limits = [-5 5];
                case 'theta0'
                    app.Value1EditField.Limits = [-5 5];
                    app.Value2EditField.Limits = [-5 5];
            end
        end

        % Value changed function: Value1EditField
        function Value1EditFieldValueChanged(app, event)
            
        end

        % Close request function: MRACIndiretoUIFigure
        function quit(app, event)
            delete(app);
            evalin('base','finish=true;')
            evalin('base','quit=true;')
        end

        % Value changed function: SaveCheckBox
        function save(app, event)
            value = app.SaveCheckBox.Value;
            if value
                evalin('base','PRINT=true;')
            else
                evalin('base','PRINT=false;')
            end
            
        end

        % Value changed function: tEditField
        function tfinal(app, event)
            evalin('base',strcat('tfinal','=',num2str(app.tEditField.Value),';'))
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MRACIndiretoUIFigure = uifigure;
            app.MRACIndiretoUIFigure.Name = 'MRAC Indireto';
            app.MRACIndiretoUIFigure.CloseRequestFcn = createCallbackFcn(app, @quit, true);
            app.Value1EditFieldLabel = uilabel(app.MRACIndiretoUIFigure);
            app.Value1EditFieldLabel.Text = 'Value 1';
            app.Value1EditField = uieditfield(app.MRACIndiretoUIFigure, 'numeric');
            app.Value1EditField.ValueChangedFcn = createCallbackFcn(app, @Value1EditFieldValueChanged, true);
            app.Value1EditField.Limits = [0 1];
            app.Value2EditFieldLabel = uilabel(app.MRACIndiretoUIFigure);
            app.Value2EditFieldLabel.Text = 'Value 2';
            app.Value2EditField = uieditfield(app.MRACIndiretoUIFigure, 'numeric');
            app.Value2EditField.Limits = [0 1];
            app.CompareButton = uibutton(app.MRACIndiretoUIFigure, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.ParameterDropDownLabel = uilabel(app.MRACIndiretoUIFigure);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.MRACIndiretoUIFigure);
            app.ParameterDropDown.Items = {'ap', 'am', 'af', 'gamma', 'yp0', 'theta0', '------'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Value = '------';
            app.SaveCheckBox = uicheckbox(app.MRACIndiretoUIFigure);
            app.SaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @save, true);
            app.SaveCheckBox.Text = 'Save';
            app.tEditFieldLabel = uilabel(app.MRACIndiretoUIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.MRACIndiretoUIFigure, 'numeric');
            app.tEditField.ValueChangedFcn = createCallbackFcn(app, @tfinal, true);
            app.tEditField.Limits = [5 200];
            app.tEditField.Value = 10;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_indireto

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRACIndiretoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRACIndiretoUIFigure)
        end
    end
end